classdef app_beta < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup_menu                  matlab.ui.container.TabGroup
        histogram                      matlab.ui.container.Tab
        TabGroup                       matlab.ui.container.TabGroup
        histogram_gray                 matlab.ui.container.Tab
        UIAxes_grayimage               matlab.ui.control.UIAxes
        UIAxes_grayimage_histogram     matlab.ui.control.UIAxes
        histogram_equalization         matlab.ui.container.Tab
        UIAxes_equalimage              matlab.ui.control.UIAxes
        UIAxes_equalimage_histogram    matlab.ui.control.UIAxes
        histogram_match                matlab.ui.container.Tab
        Label_filepath_match           matlab.ui.control.Label
        Button_matchimage              matlab.ui.control.Button
        UIAxes_matchimage              matlab.ui.control.UIAxes
        UIAxes_matchimage_histogram    matlab.ui.control.UIAxes
        UIAxes_matchedimage            matlab.ui.control.UIAxes
        UIAxes_matchedimage_histogram  matlab.ui.control.UIAxes
        enhance                        matlab.ui.container.Tab
        TabGroup2                      matlab.ui.container.TabGroup
        transformation_linear          matlab.ui.container.Tab
        Label_formula1                 matlab.ui.control.Label
        Button_1                       matlab.ui.control.Button
        input_1                        matlab.ui.control.NumericEditField
        Label_2                        matlab.ui.control.Label
        UIAxes_tanimage                matlab.ui.control.UIAxes
        transformation_nonlinear       matlab.ui.container.Tab
        Label_formula2                 matlab.ui.control.Label
        Label_formula3                 matlab.ui.control.Label
        Button_2                       matlab.ui.control.Button
        Button_3                       matlab.ui.control.Button
        input_2                        matlab.ui.control.NumericEditField
        input_3                        matlab.ui.control.NumericEditField
        input_4                        matlab.ui.control.NumericEditField
        input_5                        matlab.ui.control.NumericEditField
        EditField_5Label               matlab.ui.control.Label
        EditField_4Label               matlab.ui.control.Label
        EditField_3Label               matlab.ui.control.Label
        EditField_2Label               matlab.ui.control.Label
        UIAxes_logimage                matlab.ui.control.UIAxes
        UIAxes_expimage                matlab.ui.control.UIAxes
        size                           matlab.ui.container.Tab
        input_height                   matlab.ui.control.NumericEditField
        input_weight                   matlab.ui.control.NumericEditField
        input_angle                    matlab.ui.control.NumericEditField
        Button_changesize              matlab.ui.control.Button
        EditField_3Label_2             matlab.ui.control.Label
        EditField_2Label_2             matlab.ui.control.Label
        Label_4                        matlab.ui.control.Label
        UIAxes_sizeimage               matlab.ui.control.UIAxes
        noise                          matlab.ui.container.Tab
        TabGroup4                      matlab.ui.container.TabGroup
        Tab_3                          matlab.ui.container.Tab
        input_ave_w                    matlab.ui.control.NumericEditField
        Button_6                       matlab.ui.control.Button
        EditField_5Label_2             matlab.ui.control.Label
        Tab_4                          matlab.ui.container.Tab
        input_gau_v                    matlab.ui.control.NumericEditField
        input_gau_w                    matlab.ui.control.NumericEditField
        Label_10                       matlab.ui.control.Label
        Button_7                       matlab.ui.control.Button
        EditField_6Label               matlab.ui.control.Label
        Tab_5                          matlab.ui.container.Tab
        input_mid_w                    matlab.ui.control.NumericEditField
        Button_8                       matlab.ui.control.Button
        Label_8                        matlab.ui.control.Label
        Tab_6                          matlab.ui.container.Tab
        input_dou_w                    matlab.ui.control.NumericEditField
        input_dou_s                    matlab.ui.control.NumericEditField
        input_dou_r                    matlab.ui.control.NumericEditField
        Button_9                       matlab.ui.control.Button
        Label_7                        matlab.ui.control.Label
        Label_6                        matlab.ui.control.Label
        EditField_8Label               matlab.ui.control.Label
        Tab_7                          matlab.ui.container.Tab
        input_ilpf_d                   matlab.ui.control.NumericEditField
        Button_10                      matlab.ui.control.Button
        EditField_11Label              matlab.ui.control.Label
        TabGroup3                      matlab.ui.container.TabGroup
        Tab                            matlab.ui.container.Tab
        input_gaussian_m               matlab.ui.control.NumericEditField
        input_gaussian_v               matlab.ui.control.NumericEditField
        Button_gaussian                matlab.ui.control.Button
        EditField_3Label_3             matlab.ui.control.Label
        Label_5                        matlab.ui.control.Label
        Tab_2                          matlab.ui.container.Tab
        input_salt_d                   matlab.ui.control.NumericEditField
        Button_salt                    matlab.ui.control.Button
        EditField_4Label_2             matlab.ui.control.Label
        Label_choice                   matlab.ui.control.NumericEditField
        UIAxes_noiseimage              matlab.ui.control.UIAxes
        UIAxes_noiseimage_remove       matlab.ui.control.UIAxes
        operate                        matlab.ui.container.Tab
        TabGroup5                      matlab.ui.container.TabGroup
        Tab_8                          matlab.ui.container.Tab
        KMEANSButton                   matlab.ui.control.Button
        input_split_k                  matlab.ui.control.NumericEditField
        Label_9                        matlab.ui.control.Label
        OTSUButton                     matlab.ui.control.Button
        UIAxes_splitimage              matlab.ui.control.UIAxes
        Tab_9                          matlab.ui.container.Tab
        TabGroup6                      matlab.ui.container.TabGroup
        LBPTab                         matlab.ui.container.Tab
        input_lbp_p                    matlab.ui.control.NumericEditField
        input_lbp_r                    matlab.ui.control.NumericEditField
        Button_LBP                     matlab.ui.control.Button
        EditField_14Label              matlab.ui.control.Label
        Label_11                       matlab.ui.control.Label
        HOGTab                         matlab.ui.container.Tab
        input_hog_cell                 matlab.ui.control.NumericEditField
        Label_12                       matlab.ui.control.Label
        Button_HOG                     matlab.ui.control.Button
        UIAxes_featureimage            matlab.ui.control.UIAxes
        LaplaceButton                  matlab.ui.control.Button
        SobelButton                    matlab.ui.control.Button
        PrewittButton                  matlab.ui.control.Button
        RobertsButton                  matlab.ui.control.Button
        UIAxes_edgeimage               matlab.ui.control.UIAxes
        Label_filepath                 matlab.ui.control.Label
        button_readimage               matlab.ui.control.Button
        UIAxes_image                   matlab.ui.control.UIAxes
    end

    methods (Access = public)
        function histo = gethistogram(app, image)
            histo = zeros(256,1);
            [h,w] = size(image);
            for x = 1:w
                for y = 1:h
                    histo(image(y,x)+1) = histo(image(y,x)+1) + 1;
                end
            end
        end

        function newimage = equalization(app, image)
            histo = imhist(image);
            [h,w] = size(image);
            newimage = zeros(h,w);
            s = zeros(256,1);
            s(1) = histo(1);
            for t = 2:256
                s(t) = s(t-1) + histo(t);
            end
            for x = 1:h
                for y = 1:w
                    newimage(x,y) = s(image(x,y)+1) / (h*w);
                end
            end
            newimage = im2uint8(newimage);
        end

        function newimage = match(app, image1, image2)
            histo = imhist(image2);
            newimage = histeq(image1,histo);
        end

        function newimage = linear(app, image, x)
            x = pi * x / 180;
            image = im2double(image);
            newimage = image * tan(x);
            newimage = min(255,newimage);
            newimage = max(0,newimage);
            newimage = im2uint8(newimage);
        end

        function newimage = nonlinearlog(app, image, c)
            image = double(image);
            newimage = (log(image + 1)) * c;
            newimage = min(255,newimage);
            newimage = max(0,newimage);
            newimage = uint8(newimage);
        end

        function newimage = nonlinearexp(app, image, a, b, c)
            image = double(image);
            newimage = power(b,(c * (image - a))) - 1;
            newimage = min(255,newimage);
            newimage = max(0,newimage);
            newimage = uint8(newimage);
        end

        function new_image = suofang(app,image,kx,ky)
            [h,w,d] = size(image);
            new_h = round(h*kx);
            new_w = round(w*ky);
            new_image = zeros(new_h,new_w,d);
            %缩放坐标变换
            for new_x = 1:new_h
                for new_y = 1:new_w
                    for new_d = 1:d
                        ini_x = new_x / kx;
                        ini_y = new_y / ky;
                        x = floor(ini_x);
                        y = floor(ini_y);
                        a = ini_x - x;
                        b = ini_y - y;
                        %超出边界取边界
                        x = max(1,x);
                        y = max(1,y);
                        x = min(h-1,x);
                        y = min(w-1,y);
                        %双线性插值
                        a1 = image(x,y,new_d);
                        a2 = image(x,y+1,new_d);
                        a3 = image(x+1,y,new_d);
                        a4 = image(x+1,y+1,new_d);
                        b1 = a1 + b * (a2 - a1);
                        b2 = a3 + b * (a4 - a3);
                        new_image(new_x,new_y,new_d) = b1 + a * (b2 - b1);
                    end
                end
            end
            new_image = uint8(new_image);
        end

        function new_image = xuanzhuan(app,image,degree)
            [h,w,d] = size(image);
            %计算旋转后的图像大小
            x1 = (w-1)*cosd(degree);
            x2 = (h-1)*sind(degree);
            x3 = x1+x2;
            y1 = (1-w)*sind(degree);
            y2 = (h-1)*cosd(degree);
            y3 = y1+y2;
            minx = min(0,min(x1,min(x2,x3)));
            miny = min(0,min(y1,min(y2,y3)));
            new_h = max(0,max(y1,max(y2,y3))) - miny + 1;
            new_w = max(0,max(x1,max(x2,x3))) - minx + 1;
            new_h = round(new_h);
            new_w = round(new_w);
            new_image = zeros(new_h,new_w,d);
            %旋转坐标变换
            for new_x = 1:new_w
                for new_y = 1:new_h
                    for new_d = 1:d
                        ini_x = (new_x-1 + minx)*cosd(degree) - (new_y-1 + miny)*sind(degree);
                        ini_y = (new_x-1 + minx)*sind(degree) + (new_y-1 + miny)*cosd(degree);
                        %交换坐标轴
                        ini_x = ini_x + ini_y;
                        ini_y = ini_x - ini_y;
                        ini_x = ini_x - ini_y;
                        %变换到f(x+a,x+b)
                        x = floor(ini_x)+1;
                        y = floor(ini_y)+1;
                        a = ini_x - x;
                        b = ini_y - y;
                        %超出边界取边界
                        x = max(1,x);
                        y = max(1,y);
                        x = min(h-1,x);
                        y = min(w-1,y);
                        %双线性插值
                        a1 = image(x,y,new_d);
                        a2 = image(x,y+1,new_d);
                        a3 = image(x+1,y,new_d);
                        a4 = image(x+1,y+1,new_d);
                        b1 = a1 + b * (a2 - a1);
                        b2 = a3 + b * (a4 - a3);
                        new_image(new_y,new_x,new_d) = b1 + a * (b2 - b1);
                    end
                end
            end
            new_image = uint8(new_image);
        end

        function new_image = addnoise_gaussian(app,image,m,v)
            image = rgb2gray(image);
            image = im2double(image);
            new_image = imnoise(image,'gaussian',m,v);
            new_image = im2uint8(new_image);
        end

        function new_image = addnoise_salt(app,image,d)
            image = rgb2gray(image);
            image = im2double(image);
            new_image = imnoise(image,'salt & pepper',d);
            new_image = im2uint8(new_image);
        end

        function new_image = avergeF(app,image,matri)
            new_image = double(image);
            [h,w] = size(image);
            x = (matri-1)/2;
            for i = x+1:w-x
                for j = x+1:h-x
                    box = image(j-x:j+x,i-x:i+x);
                    f = sum(box(:));
                    f = f / (matri*matri);
                    new_image(j,i) = f;
                end
            end
            new_image = uint8(new_image);
        end

        function new_image = gaussianF(app,image,v,matri)
            new_image = imgaussfilt(image,v,"FilterSize",matri);
        end

        function new_image = midF(app,image,matri)
            new_image = medfilt2(image,[matri matri]);
        end

        function new_image = doubleF(app,image,matri,sigma_s,sigma_r)
            new_image = imbilatfilt(image,sigma_r,sigma_s,"NeighborhoodSize",matri);
        end

        function new_image = ILPF(app,image,d0)
            image = fftshift(fft2(double(image)));
            [h,w] = size(image);
            new_image = zeros(h,w);
            r1 = floor(w/2);
            r2 = floor(h/2);
            for x = 1:w
                for y = 1:h
                    d = sqrt((x-r1)^2+(y-r2)^2);
                    if d <= d0
                        H=1;
                    else
                        H=0;
                    end
                    new_image(y,x) = H * image(y,x);
                end
            end
            new_image = real(ifft2(ifftshift(new_image)));
            new_image = uint8(new_image);
        end

        function new_image = edgextract_roberts(app,image)
            image = rgb2gray(image);
            image = double(image);
            [h,w] = size(image);
            new_image = uint8(zeros(h,w));
            H_x = [1,0;0,-1];
            H_y = [0,-1;1,0];
            for i = 1:w-1
                for j = 1:h-1
                    box = image(j:j+1,i:i+1);
                    f_x = H_x .* box;
                    f_x = sum(f_x(:));
                    f_y = H_y .* box;
                    f_y = sum(f_y(:));
                    new_image(j,i) = abs(f_x) + abs(f_y);
                end
            end
        end

        function new_image = edgextract_sobel(app,image)
            image = rgb2gray(image);
            image = double(image);
            [h,w] = size(image);
            new_image = uint8(zeros(h,w));
            H_x = [-1,0,1;-2,0,2;-1,0,1];
            H_y = [-1,-2,-1;0,0,0;1,2,1];
            for i = 2:w-1
                for j = 2:h-1
                    box = image(j-1:j+1,i-1:i+1);
                    f_x = H_x .* box;
                    f_x = sum(f_x(:));
                    f_y = H_y .* box;
                    f_y = sum(f_y(:));
                    new_image(j,i) = abs(f_x) + abs(f_y);
                end
            end
        end

        function new_image = edgextract_prewitt(app,image)
            image = rgb2gray(image);
            image = double(image);
            [h,w] = size(image);
            new_image = uint8(zeros(h,w));
            H_x = [-1,0,1;-1,0,1;-1,0,1];
            H_y = [-1,-1,-1;0,0,0;1,1,1];
            for i = 2:w-1
                for j = 2:h-1
                    box = image(j-1:j+1,i-1:i+1);
                    f_x = H_x .* box;
                    f_x = sum(f_x(:));
                    f_y = H_y .* box;
                    f_y = sum(f_y(:));
                    new_image(j,i) = abs(f_x) + abs(f_y);
                end
            end
        end

        function new_image = edgextract_laplace(app,image)
            image = rgb2gray(image);
            image = double(image);
            [h,w] = size(image);
            new_image = uint8(zeros(h,w));
            H = [0,1,0;1,-4,1;0,1,0];
            for i = 2:w-1
                for j = 2:h-1
                    box = image(j-1:j+1,i-1:i+1);
                    f = H .* box;
                    f = sum(f(:));
                    %运算产生负值，可abs(f)，可f + x产生浮雕效果
                    new_image(j,i) = abs(f);
                end
            end
        end

        function new_image = OTSU(app,image)
            image = rgb2gray(image);
            T = graythresh(image);
            new_image = imbinarize(image,T);
        end

        function new_image = kjunzhi(app,image,k)
            hsv = rgb2hsv(image);
            h = hsv(:,:,1);
            h(h>330/360) = 0;
            training = h(:);
            startdata = linspace(0,1,k);
            startdata = startdata(:);
            IDX = kmeans(training,k,'Start',startdata);
            idbw = (IDX==1);
            new_image = reshape(idbw,size(h));
        end

        function new_image = LBP(app,image,num,radius)
            image = rgb2gray(image);
            [n,m] = size(image);
            P = num;R = radius;
            clbp = zeros(n,m);
            for j = 1+R:n-R
                for i = 1+R:m-R
                    count = 0;
                    for k=0:P-1
                        x = i + R*cos(2*pi*k/P);
                        y = j + R*sin(2*pi*k/P);
                        Lowx=floor(x);Highx=ceil(x);Lowy=floor(y);Highy=ceil(y);
                        coex = x - Lowx;
                        coey = y - Lowy;
                        a = image(Lowy,Lowx) + coex*(image(Lowy,Highx)-image(Lowy,Lowx));
                        b = image(Highy,Lowx) + coex*(image(Highy,Highx)-image(Highy,Lowx));
                        pixel = a + coey*(b-a);
                        if pixel>image(j,i)
                            count = count + 2^(P-1-k);
                        end
                    end
                    lbp = dec2bin(count);
                    mincount = count;
                    for k = 1:P-1
                        lbp = circshift(lbp',1)';
                        count = bin2dec(lbp);
                        if mincount>count
                            mincount = count;
                        end
                    end
                    clbp(j,i) = mincount;
                end
            end
            new_image = uint8(clbp);
        end

        function new_image = HOG(app,image, cell_size)
            image = rgb2gray(image);
            image = double(image);
            [Gx, Gy] = imgradientxy(image);
            [mag, ang] = imgradient(Gx, Gy);
            [height, width] = size(image);
            num_cells_x = floor(width / cell_size);
            num_cells_y = floor(height / cell_size);
            new_image = zeros(height, width);
            for i = 1:num_cells_y
                for j = 1:num_cells_x
                    cell_x_start = (j-1) * cell_size + 1;
                    cell_x_end = j * cell_size;
                    cell_y_start = (i-1) * cell_size + 1;
                    cell_y_end = i * cell_size;
                    cell_mag = mag(cell_y_start:cell_y_end, cell_x_start:cell_x_end);
                    cell_ang = ang(cell_y_start:cell_y_end, cell_x_start:cell_x_end);
                    for x = 1:cell_size
                        for y = 1:cell_size
                            angle = cell_ang(y, x);
                            magnitude = cell_mag(y, x);
                            new_image(cell_y_start + y - 1, cell_x_start + x - 1) = magnitude;
                        end
                    end
                end
            end
            new_image = uint8(new_image);
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: button_readimage
        function button_readimageButtonPushed(app, event)
            [f,p]=uigetfile({'*.jpg;*.tif;*.png;*.gif;*.bmp','All Image Files'});  
            if (ischar(p))
               fname = strcat(p,f);
               app.Label_filepath.Text = fname;

               image = imread(fname);
               imshow(image,'parent',app.UIAxes_image);

               image = rgb2gray(image);
               imshow(image,'Parent',app.UIAxes_grayimage);

               data = gethistogram(app, image);
               bar(app.UIAxes_grayimage_histogram,data);

               newimage = equalization(app, image);
               imshow(newimage,'Parent',app.UIAxes_equalimage);

               newdata = gethistogram(app, newimage);
               bar(app.UIAxes_equalimage_histogram,newdata);
            end
        end

        % Button pushed function: Button_matchimage
        function Button_matchimagePushed(app, event)
            [f,p]=uigetfile({'*.jpg;*.tif;*.png;*.gif;*.bmp','All Image Files'});  
            if (ischar(p))
               fname = strcat(p,f);
               app.Label_filepath_match.Text = fname;

               image = imread(fname);
               image = rgb2gray(image);
               imshow(image,'Parent',app.UIAxes_matchimage);

               data = gethistogram(app, image);
               bar(app.UIAxes_matchimage_histogram,data);

               ini_image = imread(app.Label_filepath.Text);
               ini_image = rgb2gray(ini_image);
               newimage = match(app,ini_image,image);
               imshow(newimage,'Parent',app.UIAxes_matchedimage);
               newdata = gethistogram(app, newimage);
               bar(app.UIAxes_matchedimage_histogram,newdata);
               
            end
        end

        % Button pushed function: Button_1
        function Button_1Pushed(app, event)
            image = imread(app.Label_filepath.Text);
            image = rgb2gray(image);
            newimage = linear(app, image, app.input_1.Value);
            imshow(newimage,'Parent',app.UIAxes_tanimage);
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            image = imread(app.Label_filepath.Text);
            image = rgb2gray(image);
            newimage = nonlinearlog(app, image, app.input_2.Value);
            imshow(newimage,'Parent',app.UIAxes_logimage);
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            image = imread(app.Label_filepath.Text);
            image = rgb2gray(image);
            newimage = nonlinearexp(app, image, app.input_3.Value, app.input_4.Value, app.input_5.Value);
            imshow(newimage,'Parent',app.UIAxes_expimage);
        end

        % Button pushed function: Button_changesize
        function Button_changesizePushed(app, event)
            image = imread(app.Label_filepath.Text);
            new_image = suofang(app,image,app.input_height.Value,app.input_weight.Value);
            new_image = xuanzhuan(app,new_image,app.input_angle.Value);
            imshow(new_image,'Parent',app.UIAxes_sizeimage);
        end

        % Button pushed function: Button_gaussian
        function Button_gaussianPushed(app, event)
            app.Label_choice.Value = 0;
            image = imread(app.Label_filepath.Text);
            new_image = addnoise_gaussian(app,image,app.input_gaussian_m.Value,app.input_gaussian_v.Value);
            imshow(new_image,'Parent',app.UIAxes_noiseimage);
        end

        % Button pushed function: Button_salt
        function Button_saltPushed(app, event)
            app.Label_choice.Value = 1;
            image = imread(app.Label_filepath.Text);
            new_image = addnoise_salt(app,image,app.input_salt_d.Value);
            imshow(new_image,'Parent',app.UIAxes_noiseimage);
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            image = imread(app.Label_filepath.Text);
            new_image = image;
            if app.Label_choice.Value == 0
                new_image = addnoise_gaussian(app,image,app.input_gaussian_m.Value,app.input_gaussian_v.Value);
            else
                new_image = addnoise_salt(app,image,app.input_salt_d.Value);
            end
            image = avergeF(app,new_image,app.input_ave_w.Value);
            imshow(image,'Parent',app.UIAxes_noiseimage_remove);
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            image = imread(app.Label_filepath.Text);
            new_image = image;
            if app.Label_choice.Value == 0
                new_image = addnoise_gaussian(app,image,app.input_gaussian_m.Value,app.input_gaussian_v.Value);
            else
                new_image = addnoise_salt(app,image,app.input_salt_d.Value);
            end
            image = gaussianF(app,new_image,app.input_gau_v.Value,app.input_gau_w.Value);
            imshow(image,'Parent',app.UIAxes_noiseimage_remove);
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
            image = imread(app.Label_filepath.Text);
            new_image = image;
            if app.Label_choice.Value == 0
                new_image = addnoise_gaussian(app,image,app.input_gaussian_m.Value,app.input_gaussian_v.Value);
            else
                new_image = addnoise_salt(app,image,app.input_salt_d.Value);
            end
            image = midF(app,new_image,app.input_mid_w.Value);
            imshow(image,'Parent',app.UIAxes_noiseimage_remove);
        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
            image = imread(app.Label_filepath.Text);
            new_image = image;
            if app.Label_choice.Value == 0
                new_image = addnoise_gaussian(app,image,app.input_gaussian_m.Value,app.input_gaussian_v.Value);
            else
                new_image = addnoise_salt(app,image,app.input_salt_d.Value);
            end
            image = doubleF(app,new_image,app.input_dou_w.Value,app.input_dou_s.Value,app.input_dou_r.Value);
            imshow(image,'Parent',app.UIAxes_noiseimage_remove);
        end

        % Button pushed function: Button_10
        function Button_10Pushed(app, event)
            image = imread(app.Label_filepath.Text);
            new_image = image;
            if app.Label_choice.Value == 0
                new_image = addnoise_gaussian(app,image,app.input_gaussian_m.Value,app.input_gaussian_v.Value);
            else
                new_image = addnoise_salt(app,image,app.input_salt_d.Value);
            end
            image = ILPF(app,new_image,app.input_ilpf_d.Value);
            imshow(image,'Parent',app.UIAxes_noiseimage_remove);
        end

        % Button pushed function: RobertsButton
        function RobertsButtonPushed(app, event)
            image = imread(app.Label_filepath.Text);
            new_image = edgextract_roberts(app,image);
            imshow(new_image,'Parent',app.UIAxes_edgeimage);
        end

        % Button pushed function: SobelButton
        function SobelButtonPushed(app, event)
            image = imread(app.Label_filepath.Text);
            new_image = edgextract_sobel(app,image);
            imshow(new_image,'Parent',app.UIAxes_edgeimage);
        end

        % Button pushed function: PrewittButton
        function PrewittButtonPushed(app, event)
            image = imread(app.Label_filepath.Text);
            new_image = edgextract_prewitt(app,image);
            imshow(new_image,'Parent',app.UIAxes_edgeimage);
        end

        % Button pushed function: LaplaceButton
        function LaplaceButtonPushed(app, event)
            image = imread(app.Label_filepath.Text);
            new_image = edgextract_laplace(app,image);
            imshow(new_image,'Parent',app.UIAxes_edgeimage);
        end

        % Button pushed function: OTSUButton
        function OTSUButtonPushed(app, event)
            image = imread(app.Label_filepath.Text);
            new_image = OTSU(app,image);
            imshow(new_image,'Parent',app.UIAxes_splitimage);
        end

        % Button pushed function: KMEANSButton
        function KMEANSButtonPushed(app, event)
            image = imread(app.Label_filepath.Text);
            new_image = kjunzhi(app,image,app.input_split_k.Value);
            imshow(new_image,'Parent',app.UIAxes_splitimage);
        end

        % Button pushed function: Button_LBP
        function Button_LBPPushed(app, event)
            image = imread(app.Label_filepath.Text);
            new_image = LBP(app,image,app.input_lbp_p.Value,app.input_lbp_r.Value);
            imshow(new_image,'Parent',app.UIAxes_featureimage);
        end

        % Button pushed function: Button_HOG
        function Button_HOGPushed(app, event)
            image = imread(app.Label_filepath.Text);
            new_image = HOG(app,image,app.input_hog_cell.Value);
            imshow(new_image,'Parent',app.UIAxes_featureimage);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes_image = uiaxes(app.UIFigure);
            title(app.UIAxes_image, '原图')
            xlabel(app.UIAxes_image, 'X')
            ylabel(app.UIAxes_image, 'Y')
            zlabel(app.UIAxes_image, 'Z')
            app.button_readimage = uibutton(app.UIFigure, 'push');
            app.button_readimage.ButtonPushedFcn = createCallbackFcn(app, @button_readimageButtonPushed, true);
            app.button_readimage.Text = '导入图片';
            app.Label_filepath = uilabel(app.UIFigure);
            app.Label_filepath.Text = 'filepath name';
            app.TabGroup_menu = uitabgroup(app.UIFigure);
            app.histogram = uitab(app.TabGroup_menu);
            app.histogram.Title = '直方图';
            app.TabGroup = uitabgroup(app.histogram);
            app.histogram_gray = uitab(app.TabGroup);
            app.histogram_gray.Title = '灰度直方图';
            app.UIAxes_grayimage_histogram = uiaxes(app.histogram_gray);
            title(app.UIAxes_grayimage_histogram, '直方图')
            xlabel(app.UIAxes_grayimage_histogram, 'X')
            ylabel(app.UIAxes_grayimage_histogram, 'Y')
            zlabel(app.UIAxes_grayimage_histogram, 'Z')
            app.UIAxes_grayimage = uiaxes(app.histogram_gray);
            title(app.UIAxes_grayimage, '灰度化图像')
            xlabel(app.UIAxes_grayimage, 'X')
            ylabel(app.UIAxes_grayimage, 'Y')
            zlabel(app.UIAxes_grayimage, 'Z')
            app.histogram_equalization = uitab(app.TabGroup);
            app.histogram_equalization.Title = '直方图均衡化';
            app.UIAxes_equalimage_histogram = uiaxes(app.histogram_equalization);
            title(app.UIAxes_equalimage_histogram, '直方图')
            xlabel(app.UIAxes_equalimage_histogram, 'X')
            ylabel(app.UIAxes_equalimage_histogram, 'Y')
            zlabel(app.UIAxes_equalimage_histogram, 'Z')
            app.UIAxes_equalimage = uiaxes(app.histogram_equalization);
            title(app.UIAxes_equalimage, '均衡化图像')
            xlabel(app.UIAxes_equalimage, 'X')
            ylabel(app.UIAxes_equalimage, 'Y')
            zlabel(app.UIAxes_equalimage, 'Z')
            app.histogram_match = uitab(app.TabGroup);
            app.histogram_match.Title = '直方图匹配';
            app.UIAxes_matchedimage_histogram = uiaxes(app.histogram_match);
            title(app.UIAxes_matchedimage_histogram, '直方图')
            xlabel(app.UIAxes_matchedimage_histogram, 'X')
            ylabel(app.UIAxes_matchedimage_histogram, 'Y')
            zlabel(app.UIAxes_matchedimage_histogram, 'Z')
            app.UIAxes_matchedimage = uiaxes(app.histogram_match);
            title(app.UIAxes_matchedimage, '匹配化图像')
            xlabel(app.UIAxes_matchedimage, 'X')
            ylabel(app.UIAxes_matchedimage, 'Y')
            zlabel(app.UIAxes_matchedimage, 'Z')
            app.UIAxes_matchimage_histogram = uiaxes(app.histogram_match);
            title(app.UIAxes_matchimage_histogram, '直方图')
            xlabel(app.UIAxes_matchimage_histogram, 'X')
            ylabel(app.UIAxes_matchimage_histogram, 'Y')
            zlabel(app.UIAxes_matchimage_histogram, 'Z')
            app.UIAxes_matchimage = uiaxes(app.histogram_match);
            title(app.UIAxes_matchimage, '匹配图像')
            xlabel(app.UIAxes_matchimage, 'X')
            ylabel(app.UIAxes_matchimage, 'Y')
            zlabel(app.UIAxes_matchimage, 'Z')
            app.Button_matchimage = uibutton(app.histogram_match, 'push');
            app.Button_matchimage.ButtonPushedFcn = createCallbackFcn(app, @Button_matchimagePushed, true);
            app.Button_matchimage.Text = '导入匹配图像';
            app.Label_filepath_match = uilabel(app.histogram_match);
            app.Label_filepath_match.Text = 'filepath name';
            app.enhance = uitab(app.TabGroup_menu);
            app.enhance.Title = '灰度级变换';
            app.TabGroup2 = uitabgroup(app.enhance);
            app.transformation_linear = uitab(app.TabGroup2);
            app.transformation_linear.Title = '线性变换';
            app.UIAxes_tanimage = uiaxes(app.transformation_linear);
            title(app.UIAxes_tanimage, '基础线性变换')
            xlabel(app.UIAxes_tanimage, 'X')
            ylabel(app.UIAxes_tanimage, 'Y')
            zlabel(app.UIAxes_tanimage, 'Z')
            app.Label_2 = uilabel(app.transformation_linear);
            app.Label_2.Text = 'α';
            app.input_1 = uieditfield(app.transformation_linear, 'numeric');
            app.Button_1 = uibutton(app.transformation_linear, 'push');
            app.Button_1.ButtonPushedFcn = createCallbackFcn(app, @Button_1Pushed, true);
            app.Button_1.Text = '增强';
            app.Label_formula1 = uilabel(app.transformation_linear);
            app.Label_formula1.Text = 'g(x,y)　=　f(x,y)　*　tanα';
            app.transformation_nonlinear = uitab(app.TabGroup2);
            app.transformation_nonlinear.Title = '非线性变换';
            app.UIAxes_expimage = uiaxes(app.transformation_nonlinear);
            title(app.UIAxes_expimage, '指数变换')
            xlabel(app.UIAxes_expimage, 'X')
            ylabel(app.UIAxes_expimage, 'Y')
            zlabel(app.UIAxes_expimage, 'Z')
            app.UIAxes_logimage = uiaxes(app.transformation_nonlinear);
            title(app.UIAxes_logimage, '对数变换')
            xlabel(app.UIAxes_logimage, 'X')
            ylabel(app.UIAxes_logimage, 'Y')
            zlabel(app.UIAxes_logimage, 'Z')
            app.EditField_2Label = uilabel(app.transformation_nonlinear);
            app.EditField_2Label.Text = 'ｃ';
            app.EditField_3Label = uilabel(app.transformation_nonlinear);
            app.EditField_3Label.Text = 'ａ';
            app.EditField_4Label = uilabel(app.transformation_nonlinear);
            app.EditField_4Label.Text = 'ｂ';
            app.EditField_5Label = uilabel(app.transformation_nonlinear);
            app.EditField_5Label.Text = 'ｃ';
            app.input_5 = uieditfield(app.transformation_nonlinear, 'numeric');
            app.input_4 = uieditfield(app.transformation_nonlinear, 'numeric');
            app.input_4.Value = 1;
            app.input_3 = uieditfield(app.transformation_nonlinear, 'numeric');
            app.input_2 = uieditfield(app.transformation_nonlinear, 'numeric');
            app.Button_3 = uibutton(app.transformation_nonlinear, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '增强';
            app.Button_2 = uibutton(app.transformation_nonlinear, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '增强';
            app.Label_formula3 = uilabel(app.transformation_nonlinear);
            app.Label_formula3.Text = 'g(x,y) = b^c*[f(x,y)-a] - 1';
            app.Label_formula2 = uilabel(app.transformation_nonlinear);
            app.Label_formula2.Text = 'g(x,y) ＝ c * log[f(x,y)+1]';
            app.size = uitab(app.TabGroup_menu);
            app.size.Title = '放缩旋转';
            app.UIAxes_sizeimage = uiaxes(app.size);
            title(app.UIAxes_sizeimage, 'Title')
            app.Label_4 = uilabel(app.size);
            app.Label_4.Text = '宽度';
            app.EditField_2Label_2 = uilabel(app.size);
            app.EditField_2Label_2.Text = '长度';
            app.EditField_3Label_2 = uilabel(app.size);
            app.EditField_3Label_2.Text = '角度';
            app.Button_changesize = uibutton(app.size, 'push');
            app.Button_changesize.ButtonPushedFcn = createCallbackFcn(app, @Button_changesizePushed, true);
            app.Button_changesize.Text = '尺寸变换';
            app.input_angle = uieditfield(app.size, 'numeric');
            app.input_weight = uieditfield(app.size, 'numeric');
            app.input_weight.Value = 1;
            app.input_height = uieditfield(app.size, 'numeric');
            app.input_height.Value = 1;
            app.noise = uitab(app.TabGroup_menu);
            app.noise.Title = '噪声';
            app.UIAxes_noiseimage_remove = uiaxes(app.noise);
            title(app.UIAxes_noiseimage_remove, 'Title')
            xlabel(app.UIAxes_noiseimage_remove, 'X')
            ylabel(app.UIAxes_noiseimage_remove, 'Y')
            zlabel(app.UIAxes_noiseimage_remove, 'Z')
            app.UIAxes_noiseimage = uiaxes(app.noise);
            title(app.UIAxes_noiseimage, 'Title')
            xlabel(app.UIAxes_noiseimage, 'X')
            ylabel(app.UIAxes_noiseimage, 'Y')
            zlabel(app.UIAxes_noiseimage, 'Z')
            app.Label_choice = uieditfield(app.noise, 'numeric');
            app.TabGroup3 = uitabgroup(app.noise);
            app.Tab = uitab(app.TabGroup3);
            app.Tab.Title = '高斯噪声';
            app.Label_5 = uilabel(app.Tab);
            app.Label_5.Text = '均值';
            app.EditField_3Label_3 = uilabel(app.Tab);
            app.EditField_3Label_3.Text = '方差';
            app.Button_gaussian = uibutton(app.Tab, 'push');
            app.Button_gaussian.ButtonPushedFcn = createCallbackFcn(app, @Button_gaussianPushed, true);
            app.Button_gaussian.Text = '加噪';
            app.input_gaussian_v = uieditfield(app.Tab, 'numeric');
            app.input_gaussian_v.Value = 0.01;
            app.input_gaussian_m = uieditfield(app.Tab, 'numeric');
            app.Tab_2 = uitab(app.TabGroup3);
            app.Tab_2.Title = '椒盐噪声';
            app.EditField_4Label_2 = uilabel(app.Tab_2);
            app.EditField_4Label_2.Text = '噪声密度';
            app.Button_salt = uibutton(app.Tab_2, 'push');
            app.Button_salt.ButtonPushedFcn = createCallbackFcn(app, @Button_saltPushed, true);
            app.Button_salt.Text = '加噪';
            app.input_salt_d = uieditfield(app.Tab_2, 'numeric');
            app.input_salt_d.Value = 0.05;
            app.TabGroup4 = uitabgroup(app.noise);
            app.Tab_3 = uitab(app.TabGroup4);
            app.Tab_3.Title = '均值滤波';
            app.EditField_5Label_2 = uilabel(app.Tab_3);
            app.EditField_5Label_2.Text = '窗口大小';
            app.Button_6 = uibutton(app.Tab_3, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '滤波';
            app.input_ave_w = uieditfield(app.Tab_3, 'numeric');
            app.input_ave_w.Value = 3;
            app.Tab_4 = uitab(app.TabGroup4);
            app.Tab_4.Title = '高斯滤波';
            app.EditField_6Label = uilabel(app.Tab_4);
            app.EditField_6Label.Text = '标准差';
            app.Button_7 = uibutton(app.Tab_4, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '滤波';
            app.Label_10 = uilabel(app.Tab_4);
            app.Label_10.Text = '窗口大小';
            app.input_gau_w = uieditfield(app.Tab_4, 'numeric');
            app.input_gau_w.Value = 3;
            app.input_gau_v = uieditfield(app.Tab_4, 'numeric');
            app.input_gau_v.Value = 0.6;
            app.Tab_5 = uitab(app.TabGroup4);
            app.Tab_5.Title = '中值滤波';
            app.Label_8 = uilabel(app.Tab_5);
            app.Label_8.Text = '窗口大小';
            app.Button_8 = uibutton(app.Tab_5, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = '滤波';
            app.input_mid_w = uieditfield(app.Tab_5, 'numeric');
            app.input_mid_w.Value = 5;
            app.Tab_6 = uitab(app.TabGroup4);
            app.Tab_6.Title = '双边滤波';
            app.EditField_8Label = uilabel(app.Tab_6);
            app.EditField_8Label.Text = '窗口大小';
            app.Label_6 = uilabel(app.Tab_6);
            app.Label_6.Text = '空间邻域标准差';
            app.Label_7 = uilabel(app.Tab_6);
            app.Label_7.Text = '灰度邻近标准差';
            app.Button_9 = uibutton(app.Tab_6, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Text = '滤波';
            app.input_dou_r = uieditfield(app.Tab_6, 'numeric');
            app.input_dou_r.Value = 0.1;
            app.input_dou_s = uieditfield(app.Tab_6, 'numeric');
            app.input_dou_s.Value = 3;
            app.input_dou_w = uieditfield(app.Tab_6, 'numeric');
            app.input_dou_w.Value = 3;
            app.Tab_7 = uitab(app.TabGroup4);
            app.Tab_7.Title = '理想低通滤波';
            app.EditField_11Label = uilabel(app.Tab_7);
            app.EditField_11Label.Text = '滤波半径';
            app.Button_10 = uibutton(app.Tab_7, 'push');
            app.Button_10.ButtonPushedFcn = createCallbackFcn(app, @Button_10Pushed, true);
            app.Button_10.Text = '滤波';
            app.input_ilpf_d = uieditfield(app.Tab_7, 'numeric');
            app.input_ilpf_d.Value = 10;
            app.operate = uitab(app.TabGroup_menu);
            app.operate.Title = '图像处理';
            app.UIAxes_edgeimage = uiaxes(app.operate);
            title(app.UIAxes_edgeimage, 'Title')
            xlabel(app.UIAxes_edgeimage, 'X')
            ylabel(app.UIAxes_edgeimage, 'Y')
            zlabel(app.UIAxes_edgeimage, 'Z')
            app.RobertsButton = uibutton(app.operate, 'push');
            app.RobertsButton.ButtonPushedFcn = createCallbackFcn(app, @RobertsButtonPushed, true);
            app.RobertsButton.Text = 'Roberts';
            app.PrewittButton = uibutton(app.operate, 'push');
            app.PrewittButton.ButtonPushedFcn = createCallbackFcn(app, @PrewittButtonPushed, true);
            app.PrewittButton.Text = 'Prewitt';
            app.SobelButton = uibutton(app.operate, 'push');
            app.SobelButton.ButtonPushedFcn = createCallbackFcn(app, @SobelButtonPushed, true);
            app.SobelButton.Text = 'Sobel';
            app.LaplaceButton = uibutton(app.operate, 'push');
            app.LaplaceButton.ButtonPushedFcn = createCallbackFcn(app, @LaplaceButtonPushed, true);
            app.LaplaceButton.Text = 'Laplace';
            app.TabGroup5 = uitabgroup(app.operate);
            app.Tab_8 = uitab(app.TabGroup5);
            app.Tab_8.Title = '分割';
            app.UIAxes_splitimage = uiaxes(app.Tab_8);
            title(app.UIAxes_splitimage, 'Title')
            xlabel(app.UIAxes_splitimage, 'X')
            ylabel(app.UIAxes_splitimage, 'Y')
            zlabel(app.UIAxes_splitimage, 'Z')
            app.OTSUButton = uibutton(app.Tab_8, 'push');
            app.OTSUButton.ButtonPushedFcn = createCallbackFcn(app, @OTSUButtonPushed, true);
            app.OTSUButton.Text = 'OTSU';
            app.Label_9 = uilabel(app.Tab_8);
            app.Label_9.Text = '聚类个数';
            app.input_split_k = uieditfield(app.Tab_8, 'numeric');
            app.input_split_k.Value = 6;
            app.KMEANSButton = uibutton(app.Tab_8, 'push');
            app.KMEANSButton.ButtonPushedFcn = createCallbackFcn(app, @KMEANSButtonPushed, true);
            app.KMEANSButton.Text = 'KMEANS';
            app.Tab_9 = uitab(app.TabGroup5);
            app.Tab_9.Title = '特征提取';
            app.UIAxes_featureimage = uiaxes(app.Tab_9);
            title(app.UIAxes_featureimage, 'Title')
            xlabel(app.UIAxes_featureimage, 'X')
            ylabel(app.UIAxes_featureimage, 'Y')
            zlabel(app.UIAxes_featureimage, 'Z')
            app.TabGroup6 = uitabgroup(app.Tab_9);
            app.LBPTab = uitab(app.TabGroup6);
            app.LBPTab.Title = 'LBP';
            app.Label_11 = uilabel(app.LBPTab);
            app.Label_11.Text = '采样点个数';
            app.EditField_14Label = uilabel(app.LBPTab);
            app.EditField_14Label.Text = '邻域半径';
            app.Button_LBP = uibutton(app.LBPTab, 'push');
            app.Button_LBP.ButtonPushedFcn = createCallbackFcn(app, @Button_LBPPushed, true);
            app.Button_LBP.Text = '提取';
            app.input_lbp_r = uieditfield(app.LBPTab, 'numeric');
            app.input_lbp_r.Value = 2;
            app.input_lbp_p = uieditfield(app.LBPTab, 'numeric');
            app.input_lbp_p.Value = 8;
            app.HOGTab = uitab(app.TabGroup6);
            app.HOGTab.Title = 'HOG';
            app.Button_HOG = uibutton(app.HOGTab, 'push');
            app.Button_HOG.ButtonPushedFcn = createCallbackFcn(app, @Button_HOGPushed, true);
            app.Button_HOG.Text = '提取';
            app.Label_12 = uilabel(app.HOGTab);
            app.Label_12.Text = '窗口大小';
            app.input_hog_cell = uieditfield(app.HOGTab, 'numeric');
            app.input_hog_cell.Value = 8;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_beta

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end